classdef Active_Aero_Interactive_Visulization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActiveAerodynamicsInteractiveDataVisualizationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        VehicleParametersTab            matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        VehicleParametersPanel          matlab.ui.container.Panel
        ConstantofGravityEditFieldLabel  matlab.ui.control.Label
        gravity                         matlab.ui.control.NumericEditField
        ChassisMassEditFieldLabel       matlab.ui.control.Label
        chassismass                     matlab.ui.control.NumericEditField
        TrackWidthEditFieldLabel        matlab.ui.control.Label
        trackwidth                      matlab.ui.control.NumericEditField
        MassperKnuckleandWheelEditFieldLabel  matlab.ui.control.Label
        massofwheel                     matlab.ui.control.NumericEditField
        StiffnessofAntiRollBarEditFieldLabel  matlab.ui.control.Label
        antirollbar                     matlab.ui.control.NumericEditField
        SuspensionStiffnessEditFieldLabel  matlab.ui.control.Label
        suspension                      matlab.ui.control.NumericEditField
        StaticTireRadiusEditFieldLabel  matlab.ui.control.Label
        statictireradius                matlab.ui.control.NumericEditField
        COGHeightEditFieldLabel         matlab.ui.control.Label
        heightofcog                     matlab.ui.control.NumericEditField
        CoefficientofFrictionEditFieldLabel  matlab.ui.control.Label
        coeffoffriction                 matlab.ui.control.NumericEditField
        TireRadialStiffnessEditFieldLabel  matlab.ui.control.Label
        tireradialstiffness             matlab.ui.control.NumericEditField
        TireLateralStiffnessEditFieldLabel  matlab.ui.control.Label
        tirelateralstiffness            matlab.ui.control.NumericEditField
        ConstantofGravityEditFieldLabel_2  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_3  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_4  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_5  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_6  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_7  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_8  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_9  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_10  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_11  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_12  matlab.ui.control.Label
        LengthbetweensteeringpivotsLabel  matlab.ui.control.Label
        pivotlength                     matlab.ui.control.NumericEditField
        WheelbaseEditFieldLabel         matlab.ui.control.Label
        wheelbase                       matlab.ui.control.NumericEditField
        MinimumvelocityforsystemstartEditFieldLabel  matlab.ui.control.Label
        minaerovel                      matlab.ui.control.NumericEditField
        MaximumVehicleVelocityEditFieldLabel  matlab.ui.control.Label
        maxaerovel                      matlab.ui.control.NumericEditField
        DensityofAirEditFieldLabel      matlab.ui.control.Label
        densityair                      matlab.ui.control.NumericEditField
        PercentageweightoffrontofcarEditFieldLabel  matlab.ui.control.Label
        frontbias                       matlab.ui.control.NumericEditField
        ConstantofGravityEditFieldLabel_13  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_14  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_15  matlab.ui.control.Label
        ConstantofGravityEditFieldLabel_16  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        DPlotsFrontAerofoilsTab         matlab.ui.container.Tab
        LeftWheelLoadGaugeLabel_3       matlab.ui.control.Label
        LeftWheelLoadGauge              matlab.ui.control.SemicircularGauge
        OuterWheelLoadGauge             matlab.ui.control.SemicircularGauge
        LeftWheelLoadGaugeLabel_2       matlab.ui.control.Label
        RollAngleGaugeLabel             matlab.ui.control.Label
        RollAngleGauge                  matlab.ui.control.Gauge
        ControlsPanel                   matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
        SteeringAngleKnobLabel          matlab.ui.control.Label
        SteeringAngleKnob               matlab.ui.control.Knob
        VelocityGaugeLabel              matlab.ui.control.Label
        VelocityGauge                   matlab.ui.control.Gauge
        ActiveAerodynamicsSwitchLabel   matlab.ui.control.Label
        ActiveAerodynamicsSwitch        matlab.ui.control.ToggleSwitch
        VehicleOverturnsLamp_2Label     matlab.ui.control.Label
        VehicleOverturnsLamp_2          matlab.ui.control.Lamp
        BrakePercentSpinnerLabel        matlab.ui.control.Label
        BrakePercentSpinner             matlab.ui.control.Spinner
        AcceleratorSwitch_2Label        matlab.ui.control.Label
        AcceleratorSwitch_2             matlab.ui.control.Switch
        Lamp_2                          matlab.ui.control.Lamp
        Tab                             matlab.ui.container.Tab
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes3_4                       matlab.ui.control.UIAxes
        UIAxes4_4                       matlab.ui.control.UIAxes
        ControlsPanel_2                 matlab.ui.container.Panel
        Slider_2                        matlab.ui.control.Slider
        Knob_2Label                     matlab.ui.control.Label
        Knob_2                          matlab.ui.control.Knob
        VelocityGauge_2Label            matlab.ui.control.Label
        VelocityGauge_2                 matlab.ui.control.Gauge
        ActiveAerodynamicsSwitch_2Label  matlab.ui.control.Label
        ActiveAerodynamicsSwitch_2      matlab.ui.control.ToggleSwitch
        VehicleOverturnsLampLabel       matlab.ui.control.Label
        VehicleOverturnsLamp            matlab.ui.control.Lamp
        BrakePercentSpinner_2Label      matlab.ui.control.Label
        BrakePercentSpinner_2           matlab.ui.control.Spinner
        AcceleratorSwitchLabel          matlab.ui.control.Label
        AcceleratorSwitch               matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        LeftWheelLoadGauge_2Label       matlab.ui.control.Label
        LeftWheelLoadGauge_2            matlab.ui.control.SemicircularGauge
        RollAngleGauge_2Label           matlab.ui.control.Label
        RollAngleGauge_2                matlab.ui.control.Gauge
        RearAerofoilLabel_2             matlab.ui.control.Label
        InnerWheelLoadGauge_3           matlab.ui.control.SemicircularGauge
        InnerWheelLoadGauge_2Label_2    matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        g     % [m/s^2] constant of gravity
        mc    % [kg]    chassis mass
        mw      % [kg]    mass of each knuckle and wheel
        s      % [m]     track width
        h0    % [m]     height of center of gravity
        r0    % [m]     static tire radius
        cs   % [N/m]   suspension stiffness
        cb   % [N/m]   stiffness of anti roll bar
        cy   % [N/m]   tire lateral stiffness
        cz   % [N/m]   tire radial stiffness
        s0        % [m]     Length between steering pivot points
        wb        % [m]     Wheelbase
        mu           % Coefficient of friction with road
        MinAeroVel  % [km/hr] Minimum velocity at which Active aerodynamics should start
        MaxAeroVel  % [km/hr] Maximum velocity of the car
        rho     %[kg/m^3] density of air
        weightPropF  % ratio of weight of front of car to rear
        weightPropR  % ratio of weight of front of car to rear
        TurnRight
        VehicleVelocity
        VehicleVelocityKmhr
        TurningRad
        deltaFz1 % total counter torque
        deltaFz1F % Counter torque required at front
        deltaFz1R % Counter torque required at rear
        Fz1
        Fz2
        Fz01
        Fz02
        FrontAeroData
        RearAeroData
        SteeringAngle
        SteeringAnglerad
        ay % lateral acceleration
        LiftDataF % Description
        LiftDataR
        DragDataF
        DragDataR
        RightTurn % Left or Right Turn
        Rollangle
        VelocityIndex
        LiftMaxF
        LiftMaxR
        Brake
        WingLiftFrontInner
        WingLiftFrontOuter
        WingLiftRearInner
        WingLiftRearOuter
        WingAngleFrontInner
        WingAngleFrontOuter
        WingAngleRearInner
        WingAngleRearOuter
        WingDragFrontInner
        WingDragFrontOuter
        WingDragRearInner
        WingDragRearOuter
        Accelerator % Description
        FrontWingDatasheet
        RearWingDatasheet
        AeroToggle
    end
    
    
    methods (Access = private)
        
        function  ReadVehicleData(app)
            app.g = app.gravity.Value;
            app.mc = app.chassismass.Value;
            app.mw = app.massofwheel.Value;
            app.s = app.trackwidth.Value;
            app.h0 = app.heightofcog.Value;
            app.r0 = app.statictireradius.Value;
            app.cs = app.suspension.Value;
            app.cb = app.antirollbar.Value;
            app.cy = app.tirelateralstiffness.Value;
            app.cz = app.tireradialstiffness.Value;
            app.s0 = app.pivotlength.Value;
            app.wb = app.wheelbase.Value;
            app.mu = app.coeffoffriction.Value;
            app.MinAeroVel = app.minaerovel.Value;
            app.MaxAeroVel = app.maxaerovel.Value;
            app.rho = app.densityair.Value;
            app.weightPropF = app.frontbias.Value/100;
            app.weightPropR = 1 - app.weightPropF;
            app.TurnRight = true;
            
        end
        
        function  GetRollReactions(app)
            dydz = 0 ;   % [-] lateral motion of wheel center caused by vertical
            dadz = 0/180*pi; % [Grad/m] camber change caused by vertical motion
            
            hr = app.heightofcog.Value-app.statictireradius.Value ; sh = app.trackwidth.Value/2;  dyqdz = dydz + app.statictireradius.Value*dadz; % some abbreviations


            % stiffness matrix (column by column)
            K(:,1) = [        2*app.tirelateralstiffness.Value         ; ...
                0           ; ...
                2*app.tirelateralstiffness.Value*app.heightofcog.Value        ; ...
                app.tirelateralstiffness.Value*dyqdz       ; ...
                -app.tirelateralstiffness.Value*dyqdz       ];
            K(:,2) = [         0           ; ...
                2*app.tireradialstiffness.Value         ; ...
                0           ; ...
                app.tireradialstiffness.Value          ; ...
                app.tireradialstiffness.Value          ];
            K(:,3) = [       2*app.tirelateralstiffness.Value*app.heightofcog.Value       ; ...
                0          ; ...
                2*app.tireradialstiffness.Value*sh^2+2*app.tirelateralstiffness.Value*app.heightofcog.Value^2 ; ...
                sh*app.tireradialstiffness.Value+app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value  ; ...
                -sh*app.tireradialstiffness.Value-app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value  ];
            K(:,4) = [        app.tirelateralstiffness.Value*dyqdz     ; ...
                app.tireradialstiffness.Value        ; ...
                sh*app.tireradialstiffness.Value+app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value ; ...
                app.suspension.Value+app.antirollbar.Value+app.tireradialstiffness.Value+dyqdz^2*app.tirelateralstiffness.Value ; ...
                -app.antirollbar.Value          ];
            K(:,5) = [      -app.tirelateralstiffness.Value*dyqdz      ; ...
                app.tireradialstiffness.Value         ; ...
                -sh*app.tireradialstiffness.Value-app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value  ; ...
                -app.antirollbar.Value       ; ...
                app.suspension.Value+app.antirollbar.Value+app.tireradialstiffness.Value+dyqdz^2*app.tirelateralstiffness.Value  ];
            
            b = -[ app.chassismass.Value+2*app.massofwheel.Value;  0;  2*app.massofwheel.Value*hr;  app.massofwheel.Value*dydz; -app.massofwheel.Value*dydz ]*app.ay;  % right hand side
            
            x = K\b; % solve set of linear eqautions and display results
            
            
            % compute and display wheel loads
            app.Fz1=(app.massofwheel.Value+app.chassismass.Value/2)*app.gravity.Value+app.tireradialstiffness.Value*(x(2)+app.trackwidth.Value/2*x(3)+x(4));
            app.Fz2=(app.massofwheel.Value+app.chassismass.Value/2)*app.gravity.Value+app.tireradialstiffness.Value*(x(2)-app.trackwidth.Value/2*x(3)+x(5));
            
            if app.AeroToggle == false
                
                app.Rollangle = rad2deg(x(3));
                
            elseif app.AeroToggle == true
                
                if app.deltaFz1 > (app.LiftMaxF + app.LiftMaxR)
                    
                    diff = app.deltaFz1 - (app.LiftMaxF + app.LiftMaxR);
                    proportion = diff/app.deltaFz1;
                    
                    app.Rollangle = rad2deg(x(3)*proportion);
                    
                elseif app.deltaFz1 < (app.LiftMaxF + app.LiftMaxR)
                    
                    app.Rollangle = 0;
                    
                end
                
            
            end


            app.deltaFz1 = app.Fz1 - app.Fz01;
            app.deltaFz1F = app.deltaFz1 * app.weightPropF;
            app.deltaFz1R = app.deltaFz1 * app.weightPropR;
        end
        
        function  GetRollReactionsZero(app)
            dydz = 0 ;   % [-] lateral motion of wheel center caused by vertical
            dadz = 0/180*pi; % [Grad/m] camber change caused by vertical motion
            
            hr = app.heightofcog.Value-app.statictireradius.Value ; sh = app.trackwidth.Value/2;  dyqdz = dydz + app.statictireradius.Value*dadz; % some abbreviations


            % stiffness matrix (column by column)
            K(:,1) = [        2*app.tirelateralstiffness.Value         ; ...
                0           ; ...
                2*app.tirelateralstiffness.Value*app.heightofcog.Value        ; ...
                app.tirelateralstiffness.Value*dyqdz       ; ...
                -app.tirelateralstiffness.Value*dyqdz       ];
            K(:,2) = [         0           ; ...
                2*app.tireradialstiffness.Value         ; ...
                0           ; ...
                app.tireradialstiffness.Value          ; ...
                app.tireradialstiffness.Value          ];
            K(:,3) = [       2*app.tirelateralstiffness.Value*app.heightofcog.Value       ; ...
                0          ; ...
                2*app.tireradialstiffness.Value*sh^2+2*app.tirelateralstiffness.Value*app.heightofcog.Value^2 ; ...
                sh*app.tireradialstiffness.Value+app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value  ; ...
                -sh*app.tireradialstiffness.Value-app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value  ];
            K(:,4) = [        app.tirelateralstiffness.Value*dyqdz     ; ...
                app.tireradialstiffness.Value        ; ...
                sh*app.tireradialstiffness.Value+app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value ; ...
                app.suspension.Value+app.antirollbar.Value+app.tireradialstiffness.Value+dyqdz^2*app.tirelateralstiffness.Value ; ...
                -app.antirollbar.Value          ];
            K(:,5) = [      -app.tirelateralstiffness.Value*dyqdz      ; ...
                app.tireradialstiffness.Value         ; ...
                -sh*app.tireradialstiffness.Value-app.heightofcog.Value*dyqdz*app.tirelateralstiffness.Value  ; ...
                -app.antirollbar.Value       ; ...
                app.suspension.Value+app.antirollbar.Value+app.tireradialstiffness.Value+dyqdz^2*app.tirelateralstiffness.Value  ];
            
            b = -[ app.chassismass.Value+2*app.massofwheel.Value;  0;  2*app.massofwheel.Value*hr;  app.massofwheel.Value*dydz; -app.massofwheel.Value*dydz ]*0;  % right hand side
            
            x = K\b; % solve set of linear eqautions and display results
            
            
            % compute and display wheel loads
            app.Fz01=(app.massofwheel.Value+app.chassismass.Value/2)*app.gravity.Value+app.tireradialstiffness.Value*(x(2)+app.trackwidth.Value/2*x(3)+x(4));
            app.Fz02=(app.massofwheel.Value+app.chassismass.Value/2)*app.gravity.Value+app.tireradialstiffness.Value*(x(2)-app.trackwidth.Value/2*x(3)+x(5));


        end
        
        function  ReadExcelData(app)
            app.FrontWingDatasheet =   [0.0216	-0.1250	0.0500;
                0.0209	-0.2330	0.0620;
                0.0201	-0.3310	0.0830;
                0.0195	-0.4220	0.1110;
                0.0188	-0.5070	0.1440;
                0.0181	-0.5850	0.1800;
                0.0175	-0.6570	0.2200;
                0.0168	-0.7250	0.2620;
                0.0162	-0.7880	0.3060;
                0.0172	-0.8470	0.3510;
                0.0185	-0.9020	0.3970;
                0.0196	-0.9540	0.4440;
                0.0209	-1.0030	0.4910;
                0.0222	-1.0480	0.5380;
                0.0234	-1.0920	0.5850;
                0.0247	-1.1330	0.6330;
                0.0261	-1.1710	0.6800;
                0.0274	-1.2080	0.7270;
                0.0288	-1.2390	0.7700;
                0.0302	-1.2620	0.8040;
                0.0316	-1.2760	0.8300;
                0.0330	-1.2800	0.8460;
                0.0344	-1.2750	0.8500;
                0.0359	-1.2580	0.8430;
                0.0373	-1.2290	0.8210;
                0.0387	-1.1830	0.7830;
                0.0402	-1.1180	0.7260;
                0.0416	-1.0280	0.6490];
            
            app.RearWingDatasheet =      [0.0316	0.1120	0.0320;
                0.0278	-0.0080	0.0260;
                0.0274	-0.1290	0.0260;
                0.0269	-0.2450	0.0310;
                0.0265	-0.3570	0.0410;
                0.0252	-0.4660	0.0560;
                0.0246	-0.5710	0.0760;
                0.0236	-0.6720	0.0990;
                0.0226	-0.7710	0.1250;
                0.0237	-0.8660	0.1550;
                0.0256	-0.9570	0.1880;
                0.0276	-1.0460	0.2220;
                0.0297	-1.1320	0.2590;
                0.0319	-1.2160	0.2980;
                0.0340	-1.2970	0.3390;
                0.0366	-1.3750	0.3810;
                0.0384	-1.4510	0.4240;
                0.0407	-1.5240	0.4690;
                0.0429	-1.5890	0.5110;
                0.0451	-1.6390	0.5460;
                0.0474	-1.6720	0.5730;
                0.0496	-1.6870	0.5910;
                0.0519	-1.6820	0.5980;
                0.0541	-1.6560	0.5930;
                0.0564	-1.6050	0.5750;
                0.0587	-1.5260	0.5450;
                0.0610	-1.4150	0.5020;
                0.0634	-1.2660	0.4480];
            
        end
        
        function GenerateWingData(app)
            
            %Front Wing data
            NoOfVals = app.MaxAeroVel - app.MinAeroVel;
            
            app.LiftDataF = zeros(28,NoOfVals);
            app.DragDataF = zeros(28,NoOfVals);
            velocity = app.MinAeroVel:app.MaxAeroVel;
            
            
            for j = 1:NoOfVals
                for i = 1:28
                    app.LiftDataF(i,j) = -0.5 * app.rho * app.FrontWingDatasheet(i,1) .* velocity(j)^2 * app.FrontWingDatasheet(i,2);
                    app.DragDataF(i,j) = 0.5 * app.rho * app.FrontWingDatasheet(i,1) .* velocity(j)^2 * app.FrontWingDatasheet(i,3);
                end
            end
            
            clear i j NoOfVals;
            
            %Rear Wing data
            NoOfVals = app.MaxAeroVel - app.MinAeroVel;
            
            app.LiftDataR = zeros(28,NoOfVals);
            app.DragDataR = zeros(28,NoOfVals);
            velocity = app.MinAeroVel:app.MaxAeroVel;
            
            
            for j = 1:NoOfVals
                for i = 1:28
                    app.LiftDataR(i,j) = -0.5 * app.rho * app.RearWingDatasheet(i,1) .* velocity(j)^2 * app.RearWingDatasheet(i,2);
                    app.DragDataR(i,j) = 0.5 * app.rho * app.RearWingDatasheet(i,1) .* velocity(j)^2 * app.RearWingDatasheet(i,3);
                end
            end
            
            clear i j NoOfVals;
            
        end
        
        function  UpdatePlots(app)


            if app.AeroToggle == false
                % add code for left and right here
                if app.TurnRight == true
                    app.RollAngleGauge.Value = -app.Rollangle;
                    app.RollAngleGauge_2.Value = -app.Rollangle;
                    app.LeftWheelLoadGauge.Value = app.Fz1;
                    app.OuterWheelLoadGauge.Value = app.Fz2;
                    app.LeftWheelLoadGauge_2.Value = app.Fz1;
                    app.InnerWheelLoadGauge_3.Value = app.Fz2;


                elseif app.TurnRight == false
                    
                    app.RollAngleGauge.Value = app.Rollangle;
                    app.RollAngleGauge_2.Value = app.Rollangle;
                    app.LeftWheelLoadGauge.Value = app.Fz2;
                    app.OuterWheelLoadGauge.Value = app.Fz1;
                    app.LeftWheelLoadGauge_2.Value = app.Fz2;
                    app.InnerWheelLoadGauge_3.Value = app.Fz1;
                    
                end
            elseif app.AeroToggle == true
                    
                if app.TurnRight == true
                    app.RollAngleGauge.Value = -app.Rollangle;
                    app.RollAngleGauge_2.Value = -app.Rollangle;
                    app.LeftWheelLoadGauge.Value = app.Fz1 + app.WingLiftFrontOuter + app.WingLiftRearOuter;
                    app.OuterWheelLoadGauge.Value = app.Fz2 + app.WingLiftFrontInner + app.WingLiftRearInner;
                    app.LeftWheelLoadGauge_2.Value = app.Fz1 + app.WingLiftFrontOuter + app.WingLiftRearOuter;
                    app.InnerWheelLoadGauge_3.Value = app.Fz2 + app.WingLiftFrontInner + app.WingLiftRearInner;
                    
                elseif app.TurnRight == false
                    
                    app.RollAngleGauge.Value = app.Rollangle;
                    app.RollAngleGauge_2.Value = app.Rollangle;
                    app.LeftWheelLoadGauge.Value = app.Fz2 + app.WingLiftFrontInner + app.WingLiftRearInner;
                    app.OuterWheelLoadGauge.Value = app.Fz1 + app.WingLiftFrontOuter + app.WingLiftRearOuter;
                    app.LeftWheelLoadGauge_2.Value = app.Fz2 + app.WingLiftFrontInner + app.WingLiftRearInner;
                    app.InnerWheelLoadGauge_3.Value = app.Fz1 + app.WingLiftFrontOuter + app.WingLiftRearOuter;
                    
                end
            end
            
            if app.Fz2 < 0
                app.VehicleOverturnsLamp.Enable = 'on';
                app.VehicleOverturnsLamp_2.Enable = 'on';
            elseif app.Fz2 > 0
                app.VehicleOverturnsLamp.Enable = 'off';
                app.VehicleOverturnsLamp_2.Enable = 'off';
            end
        end
        
        function AeroCalculations(app)
            
            app.VelocityIndex = abs(round(app.VehicleVelocityKmhr - app.MinAeroVel));
            if app.VelocityIndex == 0
                app.VelocityIndex = 1;
            elseif app.VelocityIndex < 0
                app.VelocityIndex = -app.VelocityIndex;
            end
            
            if app.Brake ~= 0
                app.Lamp_2.Enable = 'on';
                app.Lamp.Enable = 'on';
            end
            
            if app.Brake >= 0.65
                
                [app.WingDragFrontInner,app.WingAngleFrontInner] = max(app.DragDataF(:,app.VelocityIndex));
                [app.WingDragFrontOuter,app.WingAngleFrontOuter] = max(app.DragDataF(:,app.VelocityIndex));
                [app.WingDragRearInner,app.WingAngleRearInner] = max(app.DragDataR(:,app.VelocityIndex));
                [app.WingDragRearOuter,app.WingAngleRearOuter] = max(app.DragDataR(:,app.VelocityIndex));
                
                app.WingAngleFrontInner = app.WingAngleFrontInner - 8;
                app.WingAngleFrontOuter = app.WingAngleFrontOuter - 8;
                app.WingAngleRearInner = app.WingAngleRearInner - 8;
                app.WingAngleRearOuter = app.WingAngleRearOuter - 8;
                
            elseif app.Brake < 0.65
                
                app.LiftMaxF = max(app.LiftDataF(:,app.VelocityIndex));
                app.LiftMaxR = max(app.LiftDataR(:,app.VelocityIndex));
                
                % For Front
                if app.deltaFz1F >= app.LiftMaxF
                    % angle of attack of inner is at max lift postion and lift angle of
                    % inner is at min lift position
                    [app.WingLiftFrontInner,app.WingAngleFrontInner] = max(app.LiftDataF(:,app.VelocityIndex));
                    [app.WingLiftFrontOuter,app.WingAngleFrontOuter] = min(app.LiftDataF(:,app.VelocityIndex));
                    
                elseif app.deltaFz1F < app.LiftMaxF && app.deltaFz1F ~= 0 && app.Accelerator == false
                    % attack angle of inner is at max and outer is a function of velocity
                    [app.WingLiftFrontInner,app.WingAngleFrontInner] = max(app.LiftDataF(:,app.VelocityIndex));
                    
                    % Finding out the required value of Lift on the outer wing
                    WingLiftFrontOuterReqd = app.WingLiftFrontInner - app.deltaFz1F;
                    
                    % Finding the closest wing angle to that value
                    [~,app.WingAngleFrontOuter] = min(abs(app.LiftDataF(:,app.VelocityIndex) - WingLiftFrontOuterReqd ));
                    app.WingLiftFrontOuter = app.LiftDataF(app.WingAngleFrontOuter,app.VelocityIndex);
                    
                    
                elseif app.deltaFz1F < app.LiftMaxF && app.deltaFz1F ~= 0 && app.Accelerator == true
                    % attack angle of outer is at min and inner is a function of velocity
                    [app.WingLiftFrontOuter,app.WingAngleFrontOuter] = min(app.LiftDataF(:,app.VelocityIndex));
                    
                    % Finding out the required value of Lift on the inner wing
                    WingLiftFrontInnerReqd = app.WingLiftFrontOuter + app.deltaFz1F;
                    
                    % Finding the closest wing angle to that value
                    [~,app.WingAngleFrontInner] = min(abs(app.LiftDataF(:,app.VelocityIndex) - WingLiftFrontInnerReqd ));
                    app.WingLiftFrontInner = app.LiftDataF(app.WingAngleFrontInner,app.VelocityIndex);
                    
                elseif app.deltaFz1F == 0
                    % all wings are at minimum drag positions
                    [app.WingLiftFrontInner,app.WingAngleFrontInner] = min(app.LiftDataF(:,app.VelocityIndex));
                    [app.WingLiftFrontOuter,app.WingAngleFrontOuter] = min(app.LiftDataF(:,app.VelocityIndex));
                end
    
                % For Rear
                if app.deltaFz1R >= app.LiftMaxR
                    % angle of attack of inner is at max lift postion and lift angle of
                    % inner is at min lift position
                    [app.WingLiftRearInner,app.WingAngleRearInner] = max(app.LiftDataR(:,app.VelocityIndex));
                    [app.WingLiftRearOuter,app.WingAngleRearOuter] = min(app.LiftDataR(:,app.VelocityIndex));
                    
                    
                elseif app.deltaFz1R < app.LiftMaxR && app.deltaFz1R ~= 0 && app.Accelerator == false
                    % attack angle of inner is at max and outer is a function of velocity
                    [app.WingLiftRearInner,app.WingAngleRearInner] = max(app.LiftDataR(:,app.VelocityIndex));
                    
                    % Finding out the required value of Lift on the outer wing
                    WingLiftRearOuterReqd = app.WingLiftRearInner - app.deltaFz1R;
                    
                    % Finding the closest wing angle to that value
                    [~,app.WingAngleRearOuter] = min(abs(app.LiftDataR(:,app.VelocityIndex) - WingLiftRearOuterReqd ));
                    app.WingLiftRearOuter = app.LiftDataR(app.WingAngleRearOuter,app.VelocityIndex);
                    
                    
                elseif app.deltaFz1R < app.LiftMaxR && app.deltaFz1R ~= 0 && app.Accelerator == true
                    % attack angle of outer is at min and inner is a function of velocity
                    [app.WingLiftRearOuter,app.WingAngleRearOuter] = min(app.LiftDataR(:,app.VelocityIndex));
                    
                    % Finding out the required value of Lift on the inner wing
                    WingLiftRearInnerReqd = app.WingLiftRearOuter + app.deltaFz1R;
                    
                    % Finding the closest wing angle to that value
                    [~,app.WingAngleRearInner] = min(abs(app.LiftDataR(:,app.VelocityIndex) - WingLiftRearInnerReqd ));
                    app.WingLiftRearInner = app.LiftDataR(app.WingAngleRearInner,app.VelocityIndex);
                    
                elseif app.deltaFz1R == 0
                    % all wings are at minimum drag positions
                    [app.WingLiftRearInner,app.WingAngleRearInner] = min(app.LiftDataR(:,app.VelocityIndex));
                    [app.WingLiftRearOuter,app.WingAngleRearOuter] = min(app.LiftDataR(:,app.VelocityIndex));
                end
                
                % Correction of Angles
                app.WingAngleFrontInner = app.WingAngleFrontInner - 8;
                app.WingAngleFrontOuter = app.WingAngleFrontOuter - 8;
                app.WingAngleRearInner = app.WingAngleRearInner - 8;
                app.WingAngleRearOuter = app.WingAngleRearOuter - 8;
                
            end
            
            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VehicleOverturnsLamp.Enable = 'off';
            app.VehicleOverturnsLamp_2.Enable = 'off';
            app.Lamp.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.ay=0;
            app.VehicleVelocityKmhr = 40;
            app.VehicleVelocity = app.VehicleVelocityKmhr/3.6;
            
            app.WingAngleFrontInner = 0;
            app.WingAngleFrontOuter = 0;
            app.WingAngleRearInner = 0;
            app.WingAngleRearOuter = 0;
            
            app.Brake = 0;
            app.AeroToggle = false;
            app.Accelerator = true;
            ReadExcelData(app);
            GenerateWingData(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            ReadVehicleData(app)
            GetRollReactionsZero(app)
            GenerateWingData(app)
            
            angle = -7:20;
            velocity = app.MinAeroVel:app.MaxAeroVel-1;
            velocity = velocity';
            
            surf(app.UIAxes_3,angle,velocity,app.LiftDataF')
            surf(app.UIAxes7,angle,velocity,app.DragDataF')
            surf(app.UIAxes_2,angle,velocity,app.LiftDataR')
            surf(app.UIAxes8,angle,velocity,app.DragDataR')
            
            
        end

        % Value changing function: SteeringAngleKnob
        function SteeringAngleKnobValueChanging(app, event)
            changingValue = event.Value;
            app.Knob_2.Value = changingValue;
            app.SteeringAngle = changingValue;
            
            if app.SteeringAngle < 0
                app.TurnRight = false;
                app.SteeringAngle = - app.SteeringAngle;
            elseif app.SteeringAngle >= 0
                app.TurnRight = true;
                
            end
            app.SteeringAnglerad = deg2rad(app.SteeringAngle);
            app.TurningRad = (app.wb/sin(app.SteeringAnglerad/10) - (app.s-app.s0)/2);
            app.ay = app.VehicleVelocity^2/app.TurningRad;
            
            GetRollReactions(app)
            AeroCalculations(app)
            UpdatePlots(app)
        end

        % Value changing function: Knob_2
        function Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.SteeringAngleKnob.Value = changingValue;
            app.SteeringAngle = changingValue;
            
            
            if app.SteeringAngle < 0
                app.SteeringAngle = - app.SteeringAngle;
            elseif app.SteeringAngle >= 0
                app.TurnRight = true;
                
                
            end
            app.SteeringAnglerad = deg2rad(app.SteeringAngle);
            app.TurningRad = (app.wb/sin(app.SteeringAnglerad/10) - (app.s-app.s0)/2);
            app.ay = app.VehicleVelocity^2/app.TurningRad;
            
            GetRollReactions(app)
            AeroCalculations(app)
            UpdatePlots(app)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.VelocityGauge.Value = changingValue;
            app.VelocityGauge_2.Value = changingValue;
            app.Slider_2.Value = changingValue;
            
            app.VehicleVelocityKmhr = changingValue;
            app.VehicleVelocity = app.VehicleVelocityKmhr/3.6;
            
            GetRollReactions(app)
            AeroCalculations(app)
            UpdatePlots(app)
            
            
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.VelocityGauge.Value = changingValue;
            app.VelocityGauge_2.Value = changingValue;
            app.Slider.Value = changingValue;
            app.VehicleVelocityKmhr = changingValue;
            app.VehicleVelocity = app.VehicleVelocityKmhr/3.6;
            
            GetRollReactions(app)
            AeroCalculations(app)
            UpdatePlots(app)
        end

        % Value changed function: ActiveAerodynamicsSwitch, 
        % massofwheel
        function ActiveAerodynamicsSwitchValueChanged(app, event)
            value = app.ActiveAerodynamicsSwitch.Value;
            app.ActiveAerodynamicsSwitch_2.Value = value;
            if strcmp(value,'On')
                app.AeroToggle = true;
            elseif strcmp(value,'Off')
                app.AeroToggle = false;
            end
        end

        % Value changed function: ActiveAerodynamicsSwitch_2
        function ActiveAerodynamicsSwitch_2ValueChanged(app, event)
            value = app.ActiveAerodynamicsSwitch_2.Value;
            app.ActiveAerodynamicsSwitch.Value = value;
            if strcmp(value,'On')
                app.AeroToggle = true;
            elseif strcmp(value,'Off')
                app.AeroToggle = false;
            end
            
        end

        % Value changing function: BrakePercentSpinner_2
        function BrakePercentSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.BrakePercentSpinner.Value = changingValue;
            
            app.Brake = changingValue/100;
            
            
            UpdatePlots(app)
        end

        % Value changing function: BrakePercentSpinner
        function BrakePercentSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.BrakePercentSpinner_2.Value = changingValue;
            
            app.Brake = changingValue/100;
            
            UpdatePlots(app)
        end

        % Value changed function: AcceleratorSwitch
        function AcceleratorSwitchValueChanged(app, event)
            value = app.AcceleratorSwitch.Value;
            
            if strcmp(value,'On')
                app.AcceleratorSwitch_2.Value = 'On';
                app.Accelerator = true;
            elseif strcmp(value,'Off')
                app.AcceleratorSwitch_2.Value = 'Off';
                app.Accelerator = false;
            end
            
        end

        % Value changed function: AcceleratorSwitch_2
        function AcceleratorSwitch_2ValueChanged(app, event)
            value = app.AcceleratorSwitch_2.Value;
            
            
            if strcmp(value,'On')
                app.AcceleratorSwitch.Value = 'On';
                app.Accelerator = true;
            elseif strcmp(value,'Off')
                app.AcceleratorSwitch.Value = 'Off';
                app.Accelerator = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ActiveAerodynamicsInteractiveDataVisualizationLabel = uilabel(app.UIFigure);
            app.ActiveAerodynamicsInteractiveDataVisualizationLabel.Text = 'Active Aerodynamics - Interactive Data Visualization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VehicleParametersTab = uitab(app.TabGroup);
            app.VehicleParametersTab.Title = 'Vehicle Parameters';
            app.CalculateButton = uibutton(app.VehicleParametersTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VehicleParametersPanel = uipanel(app.VehicleParametersTab);
            app.VehicleParametersPanel.Title = 'Vehicle Parameters';
            app.ConstantofGravityEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel.Text = 'Constant of Gravity';
            app.gravity = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.gravity.Value = 9.81;
            app.ChassisMassEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.ChassisMassEditFieldLabel.Text = 'Chassis Mass';
            app.chassismass = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.chassismass.Value = 200;
            app.TrackWidthEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.TrackWidthEditFieldLabel.Text = 'Track Width';
            app.trackwidth = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.trackwidth.Value = 1.2;
            app.MassperKnuckleandWheelEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.MassperKnuckleandWheelEditFieldLabel.Text = 'Mass per Knuckle and Wheel ';
            app.massofwheel = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.massofwheel.ValueChangedFcn = createCallbackFcn(app, @ActiveAerodynamicsSwitchValueChanged, true);
            app.massofwheel.Value = 9;
            app.StiffnessofAntiRollBarEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.StiffnessofAntiRollBarEditFieldLabel.Text = 'Stiffness of Anti Roll Bar';
            app.antirollbar = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.antirollbar.ValueDisplayFormat = '%.0f';
            app.antirollbar.Value = 10000;
            app.SuspensionStiffnessEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.SuspensionStiffnessEditFieldLabel.Text = 'Suspension Stiffness';
            app.suspension = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.suspension.ValueDisplayFormat = '%.0f';
            app.suspension.Value = 20000;
            app.StaticTireRadiusEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.StaticTireRadiusEditFieldLabel.Text = 'Static Tire Radius';
            app.statictireradius = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.statictireradius.Value = 0.3;
            app.COGHeightEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.COGHeightEditFieldLabel.Text = 'COG Height';
            app.heightofcog = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.heightofcog.Value = 0.4;
            app.CoefficientofFrictionEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.CoefficientofFrictionEditFieldLabel.Text = 'Coefficient of Friction';
            app.coeffoffriction = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.coeffoffriction.Value = 1;
            app.TireRadialStiffnessEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.TireRadialStiffnessEditFieldLabel.Text = 'Tire Radial Stiffness';
            app.tireradialstiffness = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.tireradialstiffness.ValueDisplayFormat = '%.0f';
            app.tireradialstiffness.Value = 200000;
            app.TireLateralStiffnessEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.TireLateralStiffnessEditFieldLabel.Text = 'Tire Lateral Stiffness';
            app.tirelateralstiffness = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.tirelateralstiffness.ValueDisplayFormat = '%.0f';
            app.tirelateralstiffness.Value = 180000;
            app.ConstantofGravityEditFieldLabel_2 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_2.Text = 'm/s^2';
            app.ConstantofGravityEditFieldLabel_3 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_3.Text = 'kg';
            app.ConstantofGravityEditFieldLabel_4 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_4.Text = 'kg';
            app.ConstantofGravityEditFieldLabel_5 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_5.Text = 'm';
            app.ConstantofGravityEditFieldLabel_6 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_6.Text = 'm';
            app.ConstantofGravityEditFieldLabel_7 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_7.Text = 'm';
            app.ConstantofGravityEditFieldLabel_8 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_8.Text = 'N/m';
            app.ConstantofGravityEditFieldLabel_9 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_9.Text = 'N/m';
            app.ConstantofGravityEditFieldLabel_10 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_10.Text = 'N/m';
            app.ConstantofGravityEditFieldLabel_11 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_11.Text = 'N/m';
            app.ConstantofGravityEditFieldLabel_12 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_12.Text = 'm';
            app.LengthbetweensteeringpivotsLabel = uilabel(app.VehicleParametersPanel);
            app.LengthbetweensteeringpivotsLabel.Text = 'Length between steering pivots';
            app.pivotlength = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.pivotlength.Limits = [0 Inf];
            app.pivotlength.Value = 1.4;
            app.WheelbaseEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.WheelbaseEditFieldLabel.Text = 'Wheelbase';
            app.wheelbase = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.wheelbase.Limits = [0 Inf];
            app.wheelbase.Value = 1.7;
            app.MinimumvelocityforsystemstartEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.MinimumvelocityforsystemstartEditFieldLabel.Text = 'Minimum velocity for system start';
            app.minaerovel = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.minaerovel.Limits = [0 Inf];
            app.minaerovel.Value = 40;
            app.MaximumVehicleVelocityEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.MaximumVehicleVelocityEditFieldLabel.Text = 'Maximum Vehicle Velocity';
            app.maxaerovel = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.maxaerovel.Limits = [0 Inf];
            app.maxaerovel.Value = 160;
            app.DensityofAirEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.DensityofAirEditFieldLabel.Text = 'Density of Air';
            app.densityair = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.densityair.Limits = [0 2];
            app.densityair.Value = 1.225;
            app.PercentageweightoffrontofcarEditFieldLabel = uilabel(app.VehicleParametersPanel);
            app.PercentageweightoffrontofcarEditFieldLabel.Text = 'Percentage weight of front of car';
            app.frontbias = uieditfield(app.VehicleParametersPanel, 'numeric');
            app.frontbias.Limits = [0 100];
            app.frontbias.Value = 30;
            app.ConstantofGravityEditFieldLabel_13 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_13.Text = 'm';
            app.ConstantofGravityEditFieldLabel_14 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_14.Text = 'km/hr';
            app.ConstantofGravityEditFieldLabel_15 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_15.Text = 'km/hr';
            app.ConstantofGravityEditFieldLabel_16 = uilabel(app.VehicleParametersPanel);
            app.ConstantofGravityEditFieldLabel_16.Text = 'kg/m^3';
            app.UIAxes_2 = uiaxes(app.VehicleParametersTab);
            title(app.UIAxes_2, 'Rear Wing Lift Force (N) vs Wing Angle Vs Velocity')
            xlabel(app.UIAxes_2, 'Angle of Attack (degrees)')
            ylabel(app.UIAxes_2, 'Vehicle Velocity (km/hr)')
            app.UIAxes_3 = uiaxes(app.VehicleParametersTab);
            title(app.UIAxes_3, 'Front Wing Lift Force (N) vs Wing Angle Vs Velocity')
            xlabel(app.UIAxes_3, 'Angle of Attack (degrees)')
            ylabel(app.UIAxes_3, 'Vehicle Velocity (km/hr)')
            app.UIAxes7 = uiaxes(app.VehicleParametersTab);
            title(app.UIAxes7, 'Front Wing Drag (N) vs Wing Angle Vs Velocity')
            xlabel(app.UIAxes7, 'Angle of Attack (degrees)')
            ylabel(app.UIAxes7, 'Vehicle Velocity (km/hr)')
            app.UIAxes8 = uiaxes(app.VehicleParametersTab);
            title(app.UIAxes8, 'Drag Wing Drag Force (N) vs Wing Angle Vs Velocity')
            xlabel(app.UIAxes8, 'Angle of Attack (degrees)')
            ylabel(app.UIAxes8, 'Vehicle Velocity (km/hr)')
            app.DPlotsFrontAerofoilsTab = uitab(app.TabGroup);
            app.DPlotsFrontAerofoilsTab.Title = '2D Plots - Front Aerofoils';
            app.LeftWheelLoadGaugeLabel_3 = uilabel(app.DPlotsFrontAerofoilsTab);
            app.LeftWheelLoadGaugeLabel_3.Text = 'Left Wheel Load';
            app.LeftWheelLoadGauge = uigauge(app.DPlotsFrontAerofoilsTab, 'semicircular');
            app.LeftWheelLoadGauge.Limits = [0 5000];
            app.OuterWheelLoadGauge = uigauge(app.DPlotsFrontAerofoilsTab, 'semicircular');
            app.OuterWheelLoadGauge.Limits = [0 5000];
            app.LeftWheelLoadGaugeLabel_2 = uilabel(app.DPlotsFrontAerofoilsTab);
            app.LeftWheelLoadGaugeLabel_2.Text = 'Right Wheel Load';
            app.RollAngleGaugeLabel = uilabel(app.DPlotsFrontAerofoilsTab);
            app.RollAngleGaugeLabel.Text = 'Roll Angle';
            app.RollAngleGauge = uigauge(app.DPlotsFrontAerofoilsTab, 'circular');
            app.RollAngleGauge.Limits = [-5 5];
            app.ControlsPanel = uipanel(app.DPlotsFrontAerofoilsTab);
            app.ControlsPanel.Title = 'Controls';
            app.Slider = uislider(app.ControlsPanel);
            app.Slider.Limits = [40 160];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 40;
            app.SteeringAngleKnobLabel = uilabel(app.ControlsPanel);
            app.SteeringAngleKnobLabel.Text = 'Steering Angle';
            app.SteeringAngleKnob = uiknob(app.ControlsPanel, 'continuous');
            app.SteeringAngleKnob.Limits = [-20 20];
            app.SteeringAngleKnob.ValueChangingFcn = createCallbackFcn(app, @SteeringAngleKnobValueChanging, true);
            app.VelocityGaugeLabel = uilabel(app.ControlsPanel);
            app.VelocityGaugeLabel.Text = 'Velocity';
            app.VelocityGauge = uigauge(app.ControlsPanel, 'circular');
            app.VelocityGauge.Limits = [40 160];
            app.ActiveAerodynamicsSwitchLabel = uilabel(app.ControlsPanel);
            app.ActiveAerodynamicsSwitchLabel.Text = 'Active Aerodynamics';
            app.ActiveAerodynamicsSwitch = uiswitch(app.ControlsPanel, 'toggle');
            app.ActiveAerodynamicsSwitch.ValueChangedFcn = createCallbackFcn(app, @ActiveAerodynamicsSwitchValueChanged, true);
            app.VehicleOverturnsLamp_2Label = uilabel(app.ControlsPanel);
            app.VehicleOverturnsLamp_2Label.Text = 'Vehicle Overturns';
            app.VehicleOverturnsLamp_2 = uilamp(app.ControlsPanel);
            app.BrakePercentSpinnerLabel = uilabel(app.ControlsPanel);
            app.BrakePercentSpinnerLabel.Text = 'Brake Percent';
            app.BrakePercentSpinner = uispinner(app.ControlsPanel);
            app.BrakePercentSpinner.ValueChangingFcn = createCallbackFcn(app, @BrakePercentSpinnerValueChanging, true);
            app.BrakePercentSpinner.Limits = [0 100];
            app.AcceleratorSwitch_2Label = uilabel(app.ControlsPanel);
            app.AcceleratorSwitch_2Label.Text = {'Accelerator'; ''};
            app.AcceleratorSwitch_2 = uiswitch(app.ControlsPanel, 'slider');
            app.AcceleratorSwitch_2.ValueChangedFcn = createCallbackFcn(app, @AcceleratorSwitch_2ValueChanged, true);
            app.AcceleratorSwitch_2.Value = 'On';
            app.Lamp_2 = uilamp(app.ControlsPanel);
            app.Tab = uitab(app.TabGroup);
            app.UIAxes3_3 = uiaxes(app.Tab);
            title(app.UIAxes3_3, 'Left Wing Angle vs Lift')
            xlabel(app.UIAxes3_3, 'Angle')
            ylabel(app.UIAxes3_3, 'Lift')
            app.UIAxes3_3.Visible = 'off';
            app.UIAxes4_3 = uiaxes(app.Tab);
            title(app.UIAxes4_3, 'Right Wing Angle vs Lift')
            xlabel(app.UIAxes4_3, 'Angle')
            ylabel(app.UIAxes4_3, 'Lift')
            app.UIAxes4_3.Visible = 'off';
            app.UIAxes3_4 = uiaxes(app.Tab);
            title(app.UIAxes3_4, 'Left Wing Angle vs Drag')
            xlabel(app.UIAxes3_4, 'Angle')
            ylabel(app.UIAxes3_4, 'Drag')
            app.UIAxes3_4.Visible = 'off';
            app.UIAxes4_4 = uiaxes(app.Tab);
            title(app.UIAxes4_4, 'Right Wing Angle vs Drag')
            xlabel(app.UIAxes4_4, 'Angle')
            ylabel(app.UIAxes4_4, 'Drag')
            app.UIAxes4_4.Visible = 'off';
            app.ControlsPanel_2 = uipanel(app.Tab);
            app.ControlsPanel_2.Title = 'Controls';
            app.Slider_2 = uislider(app.ControlsPanel_2);
            app.Slider_2.Limits = [40 160];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 40;
            app.Knob_2Label = uilabel(app.ControlsPanel_2);
            app.Knob_2Label.Text = 'Knob';
            app.Knob_2 = uiknob(app.ControlsPanel_2, 'continuous');
            app.Knob_2.Limits = [-20 20];
            app.Knob_2.ValueChangingFcn = createCallbackFcn(app, @Knob_2ValueChanging, true);
            app.VelocityGauge_2Label = uilabel(app.ControlsPanel_2);
            app.VelocityGauge_2Label.Text = 'Velocity';
            app.VelocityGauge_2 = uigauge(app.ControlsPanel_2, 'circular');
            app.VelocityGauge_2.Limits = [40 160];
            app.ActiveAerodynamicsSwitch_2Label = uilabel(app.ControlsPanel_2);
            app.ActiveAerodynamicsSwitch_2Label.Text = 'Active Aerodynamics';
            app.ActiveAerodynamicsSwitch_2 = uiswitch(app.ControlsPanel_2, 'toggle');
            app.ActiveAerodynamicsSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ActiveAerodynamicsSwitch_2ValueChanged, true);
            app.VehicleOverturnsLampLabel = uilabel(app.ControlsPanel_2);
            app.VehicleOverturnsLampLabel.Text = 'Vehicle Overturns';
            app.VehicleOverturnsLamp = uilamp(app.ControlsPanel_2);
            app.BrakePercentSpinner_2Label = uilabel(app.ControlsPanel_2);
            app.BrakePercentSpinner_2Label.Text = 'Brake Percent';
            app.BrakePercentSpinner_2 = uispinner(app.ControlsPanel_2);
            app.BrakePercentSpinner_2.ValueChangingFcn = createCallbackFcn(app, @BrakePercentSpinner_2ValueChanging, true);
            app.BrakePercentSpinner_2.Limits = [0 100];
            app.AcceleratorSwitchLabel = uilabel(app.ControlsPanel_2);
            app.AcceleratorSwitchLabel.Text = 'Accelerator';
            app.AcceleratorSwitch = uiswitch(app.ControlsPanel_2, 'slider');
            app.AcceleratorSwitch.ValueChangedFcn = createCallbackFcn(app, @AcceleratorSwitchValueChanged, true);
            app.AcceleratorSwitch.Value = 'On';
            app.Lamp = uilamp(app.ControlsPanel_2);
            app.LeftWheelLoadGauge_2Label = uilabel(app.Tab);
            app.LeftWheelLoadGauge_2Label.Text = 'Left Wheel Load';
            app.LeftWheelLoadGauge_2 = uigauge(app.Tab, 'semicircular');
            app.LeftWheelLoadGauge_2.Limits = [0 5000];
            app.RollAngleGauge_2Label = uilabel(app.Tab);
            app.RollAngleGauge_2Label.Text = 'Roll Angle';
            app.RollAngleGauge_2 = uigauge(app.Tab, 'circular');
            app.RollAngleGauge_2.Limits = [-5 5];
            app.RearAerofoilLabel_2 = uilabel(app.Tab);
            app.RearAerofoilLabel_2.Text = 'Rear Aerofoil';
            app.InnerWheelLoadGauge_3 = uigauge(app.Tab, 'semicircular');
            app.InnerWheelLoadGauge_3.Limits = [0 5000];
            app.InnerWheelLoadGauge_2Label_2 = uilabel(app.Tab);
            app.InnerWheelLoadGauge_2Label_2.Text = 'Right Wheel Load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Active_Aero_Interactive_Visulization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end